classdef MatlabGUIpractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        StopButton    matlab.ui.control.Button
        StartButton   matlab.ui.control.Button
        AltAxes       matlab.ui.control.UIAxes
        PressureAxes  matlab.ui.control.UIAxes
        TempAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn
        d
        dev
        data
        ch0
        ch1
        ch2
        inTemp_v            %intput temperature in volts from DAQ
        inPres_v            %intput pressure in volts from DAQ
        Vreg                %monitor regulator voltage to make sure it is constant
        temp
        pres
        alt
        p0 = 101.333;       %pressure at sea level [kPa]
        R = 8.31;           %
        g = 9.8;            %acceleration of gravity
        m = 0.02896;        %mean molecular mass of air
        AllDataTimeTable
        timestamps
    end
    
    methods (Access = private)
        function plotData(app,scan,~)   %~ is neccessary if you are not using the third input event
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.inTemp_v = app.data(:,1);
            app.inPres_v = app.data(:,2);
            app.Vreg = app.data(:,3);
            app.temp = app.inTemp_v/0.01;
            app.pres = ((app.inPres_v./app.Vreg) + 0.095)/0.009;
            app.alt = log(app.pres/app.p0).*((-app.R*app.temp)/(app.m*app.g));
  %          app.temp = app.VtoT(app, app.inTemp_v);
   %         app.pres = app.VtoP(app,app.Vreg, app.inPres_v);
    %        app.alt = app.altitudePT(app,app.pres,app.temp,app.p0,app.R,app.g,app.m);
            app.NewDataTimeTable = timetable(datetime("now"),mean(app.inTemp_v),mean(app.inPres_v),mean(app.Vreg),mean(app.temp),mean(app.pres),mean(app.alt));
            plot(app.TempAxes,app.timestamps,app.temp,"g");
            hold(app.TempAxes, "on");
            plot(app.PressureAxes,app.timestamps,app.pres,"g");
            hold(app.PressureAxes, "on");
            plot(app.AltAxes,app.timestamps,app.alt,"g");
            hold(app.AltAxes, "on");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            devices = daqlist("ni");            %creats list of devides
            app.dev = devices(1,"DeviceID");        %saves device name of first daq found in app.dev
            app.d = daq('ni');                      %create data aquisition object, always need this line*** passes to all function that do data aquisition
            app.ch0 = addinput(app.d, app.dev.DeviceID,'ai0','voltage');        %Doesn't recomend hard coding the output channel, app.*channelname*.Value attatched to drop down list 
            app.ch1 = addinput(app.d, app.dev.DeviceID,'ai1','voltage');
            app.ch2 = addinput(app.d, app.dev.DeviceID,'ai2','voltage');
            app.ch0.TerminalConfig='Differential';
            app.ch1.TerminalConfig='Differential';
            app.ch2.TerminalConfig='Differential';
            app.d.Rate = 10;                                                  %also attatch to front panel***
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
            %cla(app.TempAxes);
 %           app.ScansAvailibleFcnCount = app.d.Rate*1;          %only aquires every x number of samples
            app.d.ScansAvailableFcn = @(scan,evt) plotData(app,scan);   %Executes once callback is met
            start(app.d,"continuous");

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            
            % save input data
            writetable(app.NewDataTimeTable,"Lab08data.xls")
            % stop aquisition
                              
            stop(app.d);
            removechannel(app.d, [1:length(app.d.Channels)]);
            %removeChannel(app.s,2);
            
            % enable component
            hold(app.TempAxes, 'off');
            app.StartButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TempAxes = uiaxes(app.UIFigure);
            title(app.TempAxes, 'Title')
            xlabel(app.TempAxes, 'X')
            ylabel(app.TempAxes, 'Y')
            zlabel(app.TempAxes, 'Z')
            app.PressureAxes = uiaxes(app.UIFigure);
            title(app.PressureAxes, 'Title')
            xlabel(app.PressureAxes, 'X')
            ylabel(app.PressureAxes, 'Y')
            zlabel(app.PressureAxes, 'Z')
            app.AltAxes = uiaxes(app.UIFigure);
            title(app.AltAxes, 'Title')
            xlabel(app.AltAxes, 'X')
            ylabel(app.AltAxes, 'Y')
            zlabel(app.AltAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabGUIpractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end