classdef MatlabGUIpractice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        StopButton   matlab.ui.control.Button
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        AnalogIn
        d
        dev
        data
        ch0
        ch1
        ch2
        inTemp_v
        inPres_v
        Vin
        timestamps
    end
    
    methods (Access = private)
        
        function plotData(app,scan,~)
            [app.data, app.timestamps,~]=read(scan, scan.ScansAvailableFcnCount,"OutputFormat","Matrix");
            app.inTemp_v = app.data(:,1);
            app.inPres_v = app.data(:,1);
            app.Vin = app.data(:,1);
            plot(app.UIAxes,app.timestamps,app.inTemp_v,"g");
            hold(app.UIAxes, "on");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StopButton.Enable = "off";
            devices = daqlist("ni");
            app.dev = devices(1,"DeviceID");
            app.d = daq('ni');
            app.ch0 = addinput(app.d, app.dev.DeviceID,'ai0','voltage');
            app.ch1 = addinput(app.d, app.dev.DeviceID,'ai1','voltage');
            app.ch2 = addinput(app.d, app.dev.DeviceID,'ai2','voltage');            
            app.d.Rate = 1000;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off";
            app.StopButton.Enable = "on";
            app.d.ScansAvailableFcn = @(scan,evt) plotData(app,scan);
            cla(app.UIAxes);
            start(app.d,"continuous");
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % disable unneccesary components
            app.StopButton.Enable = false;
            
            % save input data
            t = datestr(now, 'mm-dd-yyyy_HHMMSS');
            string = strcat('SampleCodeVoltages_',t);
            xlswrite(string, app.Vin, 'Sheet1', 'A');
            xlswrite(string, app.inPres_v,'Sheet1','B');
            xlswrite(string, app.inTemp_v,'Sheet1', 'C');
            % stop aquisition
                              
            stop(app.d);
            removechannel(app.d, [1:length(app.d.Channels)]);
            %removeChannel(app.s,2);
            
            % enable component
            hold(app.UIAxes, 'off');
            app.StartButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabGUIpractice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end